classdef FreeSpaceOpticalCommunicationsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        CalculateButton                 matlab.ui.control.Button
        Modulation                      matlab.ui.control.DropDown
        ModulationDropDownLabel         matlab.ui.control.Label
        YAxismisalignmentdegreesSlider  matlab.ui.control.Slider
        YAxismisalignmentdegreesSliderLabel  matlab.ui.control.Label
        XAxismisalignmentdegreesSlider  matlab.ui.control.Slider
        XAxismisalignmentdegreesSliderLabel  matlab.ui.control.Label
        AtmosphericConditions           matlab.ui.control.DropDown
        AtmosphericConditionsLabel      matlab.ui.control.Label
        ThetaUnits                      matlab.ui.control.DropDown
        Theta                           matlab.ui.control.NumericEditField
        BeamDivergenceLabel             matlab.ui.control.Label
        Apperture                       matlab.ui.control.EditField
        ApperturecmLabel                matlab.ui.control.Label
        BitRateUnits                    matlab.ui.control.DropDown
        BitRate                         matlab.ui.control.NumericEditField
        RequiredbitrateEditFieldLabel   matlab.ui.control.Label
        LinkLengthkm                    matlab.ui.control.NumericEditField
        LinkLengthkmEditFieldLabel      matlab.ui.control.Label
        Wavelength                      matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        WhereDropDown                   matlab.ui.control.DropDown
        WhereDropDownLabel              matlab.ui.control.Label
        TransmittedMessage              matlab.ui.control.EditField
        EntermessagetobetransmittedLabel  matlab.ui.control.Label
        TransmitterLabel                matlab.ui.control.Label
        OutputTab                       matlab.ui.container.Tab
        QualityFactor                   matlab.ui.control.NumericEditField
        SNREditFieldLabel_2             matlab.ui.control.Label
        SNR                             matlab.ui.control.NumericEditField
        SNREditFieldLabel               matlab.ui.control.Label
        BER                             matlab.ui.control.NumericEditField
        BEREditFieldLabel               matlab.ui.control.Label
        Receivedback                    matlab.ui.control.EditField
        ReceivedbackEditFieldLabel      matlab.ui.control.Label
        ReceiverLabel                   matlab.ui.control.Label
        PointingErrorsdB                matlab.ui.control.Label
        PointingErrors                  matlab.ui.control.NumericEditField
        PointingErrorsLabel             matlab.ui.control.Label
        TurbulanceEffectdB              matlab.ui.control.Label
        TurbulanceEffect                matlab.ui.control.NumericEditField
        TurbulanceEffectLabel           matlab.ui.control.Label
        GeometricalLossesdB             matlab.ui.control.Label
        GeometricalLosses               matlab.ui.control.NumericEditField
        GeometricalLossesLabel          matlab.ui.control.Label
        ScintillationdB                 matlab.ui.control.Label
        Scintillation                   matlab.ui.control.NumericEditField
        ScintillationLabel              matlab.ui.control.Label
        AtmosphericAttenuationdB        matlab.ui.control.Label
        AtmosphericAttenuation          matlab.ui.control.NumericEditField
        AtmosphericAttenuationEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % transmitted % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Inputs
            text = app.TransmittedMessage.Value;
            wavelength = app.Wavelength.Value*1e-9;
            modulation = app.Modulation.Value;
            transmission_location = app.WhereDropDown.Value;
            link_length=app.LinkLengthkm.Value;
            BR=app.BitRate.Value;
            x_misaligment=app.XAxismisalignmentdegreesSlider.Value;
            y_misaligment=app.YAxismisalignmentdegreesSlider.Value;
            misaligment=[x_misaligment,y_misaligment];
            switch app.BitRateUnits.Value
                case "kbps"
                    BR=1e3*BR;
                case "Mbps"
                    BR=1e6*BR;
                case "Gbps"
                    BR=1e9*BR;
            end
            apperture=app.Apperture.Value;
            beam_divergence=app.Theta.Value;
            if app.ThetaUnits.Value=="rad"
                beam_divergence=beam_divergence*180/pi;
            end
            atm_conditions= app.AtmosphericConditions.Value;
            [text_output,ber,snr,total_losses] = fso_simulation(text, wavelength, modulation,transmission_location,link_length,BR,apperture,beam_divergence,atm_conditions,misaligment);            %
            
            % Outputs
            app.Receivedback.Value=text_output;
            app.BER.Value=ber;
            app.SNR.Value=snr;
            app.QualityFactor.Value=sqrt(snr)/2;
            app.GeometricalLosses.Value=total_losses(3);
            app.TabGroup.SelectedTab = app.OutputTab;
            
        end

        % Value changed function: WhereDropDown
        function WhereDropDownValueChanged(app, event)
            % Visibility toggle
            if app.WhereDropDown.Value == "Ground via sat relay"
                app.LinkLengthkm.Visible ="off";
            else 
                app.LinkLengthkm.Visible="on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.TransmitterLabel = uilabel(app.InputTab);
            app.TransmitterLabel.Text = 'Transmitter';
            app.EntermessagetobetransmittedLabel = uilabel(app.InputTab);
            app.EntermessagetobetransmittedLabel.Text = {'Enter message'; 'to be transmitted:'};
            app.TransmittedMessage = uieditfield(app.InputTab, 'text');
            app.TransmittedMessage.Value = 'THE QUICK BROWN FOX JUMPED OVER THE LAZY DOG. ';
            app.WhereDropDownLabel = uilabel(app.InputTab);
            app.WhereDropDownLabel.Text = 'Where?';
            app.WhereDropDown = uidropdown(app.InputTab);
            app.WhereDropDown.Items = {'Cosmic space only', 'Ground only', 'Ground via sat relay', 'Cosmic Space from earth'};
            app.WhereDropDown.ValueChangedFcn = createCallbackFcn(app, @WhereDropDownValueChanged, true);
            app.WhereDropDown.Value = 'Cosmic space only';
            app.WavelengthnmEditFieldLabel = uilabel(app.InputTab);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.Wavelength = uieditfield(app.InputTab, 'numeric');
            app.Wavelength.Value = 1550;
            app.LinkLengthkmEditFieldLabel = uilabel(app.InputTab);
            app.LinkLengthkmEditFieldLabel.Text = 'Link Length (km)';
            app.LinkLengthkm = uieditfield(app.InputTab, 'numeric');
            app.LinkLengthkm.Value = 2;
            app.RequiredbitrateEditFieldLabel = uilabel(app.InputTab);
            app.RequiredbitrateEditFieldLabel.Text = 'Required bit rate';
            app.BitRate = uieditfield(app.InputTab, 'numeric');
            app.BitRate.Value = 1;
            app.BitRateUnits = uidropdown(app.InputTab);
            app.BitRateUnits.Items = {'bps', 'kbps', 'Mbps', 'Gbps'};
            app.BitRateUnits.Value = 'Mbps';
            app.ApperturecmLabel = uilabel(app.InputTab);
            app.ApperturecmLabel.Text = 'Apperture (m)';
            app.Apperture = uieditfield(app.InputTab, 'text');
            app.Apperture.Value = '0.2';
            app.BeamDivergenceLabel = uilabel(app.InputTab);
            app.BeamDivergenceLabel.Text = 'Beam Divergence';
            app.Theta = uieditfield(app.InputTab, 'numeric');
            app.Theta.Value = 0.002;
            app.ThetaUnits = uidropdown(app.InputTab);
            app.ThetaUnits.Items = {'rad', 'degrees'};
            app.ThetaUnits.Value = 'rad';
            app.AtmosphericConditionsLabel = uilabel(app.InputTab);
            app.AtmosphericConditionsLabel.Text = {'Atmospheric'; 'Conditions'};
            app.AtmosphericConditions = uidropdown(app.InputTab);
            app.AtmosphericConditions.Items = {'Clear Skies', 'Light Rain (<2.5 mm/hr)', 'Medium Rain (2.7 to 7.5 mm/hr)', 'Heavy Rain (7.6 to 50 mm/hr)', 'Violent Rain (>50 mm/hr)', 'Light Haze', 'Haze', 'Thin Fog', 'Moderate Fog', 'Thick Fog', 'Dense Fog'};
            app.AtmosphericConditions.Value = 'Clear Skies';
            app.XAxismisalignmentdegreesSliderLabel = uilabel(app.InputTab);
            app.XAxismisalignmentdegreesSliderLabel.Text = {'X Axis'; 'misalignment'; '(degrees)'};
            app.XAxismisalignmentdegreesSlider = uislider(app.InputTab);
            app.XAxismisalignmentdegreesSlider.Limits = [-60 60];
            app.XAxismisalignmentdegreesSlider.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.XAxismisalignmentdegreesSlider.MajorTickLabels = {'-60', '-45', '-30', '-15', '0', '15', '30', '45', '60'};
            app.YAxismisalignmentdegreesSliderLabel = uilabel(app.InputTab);
            app.YAxismisalignmentdegreesSliderLabel.Text = {'Y Axis'; 'misalignment'; '(degrees)'};
            app.YAxismisalignmentdegreesSlider = uislider(app.InputTab);
            app.YAxismisalignmentdegreesSlider.Limits = [-60 60];
            app.YAxismisalignmentdegreesSlider.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.YAxismisalignmentdegreesSlider.MajorTickLabels = {'-60', '-45', '-30', '-15', '0', '15', '30', '45', '60'};
            app.ModulationDropDownLabel = uilabel(app.InputTab);
            app.ModulationDropDownLabel.Text = 'Modulation ';
            app.Modulation = uidropdown(app.InputTab);
            app.Modulation.Items = {'OOK', 'QPSK', '16 QAM'};
            app.Modulation.Value = 'OOK';
            app.CalculateButton = uibutton(app.InputTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate!';
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.UIAxes = uiaxes(app.OutputTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AtmosphericAttenuationEditFieldLabel = uilabel(app.OutputTab);
            app.AtmosphericAttenuationEditFieldLabel.Text = 'Atmospheric Attenuation: ';
            app.AtmosphericAttenuation = uieditfield(app.OutputTab, 'numeric');
            app.AtmosphericAttenuationdB = uilabel(app.OutputTab);
            app.AtmosphericAttenuationdB.Text = 'dB';
            app.ScintillationLabel = uilabel(app.OutputTab);
            app.ScintillationLabel.Text = 'Scintillation: ';
            app.Scintillation = uieditfield(app.OutputTab, 'numeric');
            app.ScintillationdB = uilabel(app.OutputTab);
            app.ScintillationdB.Text = 'dB';
            app.GeometricalLossesLabel = uilabel(app.OutputTab);
            app.GeometricalLossesLabel.Text = 'Geometrical Losses';
            app.GeometricalLosses = uieditfield(app.OutputTab, 'numeric');
            app.GeometricalLossesdB = uilabel(app.OutputTab);
            app.GeometricalLossesdB.Text = 'dB';
            app.TurbulanceEffectLabel = uilabel(app.OutputTab);
            app.TurbulanceEffectLabel.Text = 'Turbulance Effect';
            app.TurbulanceEffect = uieditfield(app.OutputTab, 'numeric');
            app.TurbulanceEffectdB = uilabel(app.OutputTab);
            app.TurbulanceEffectdB.Text = 'dB';
            app.PointingErrorsLabel = uilabel(app.OutputTab);
            app.PointingErrorsLabel.Text = 'Pointing Errors';
            app.PointingErrors = uieditfield(app.OutputTab, 'numeric');
            app.PointingErrorsdB = uilabel(app.OutputTab);
            app.PointingErrorsdB.Text = 'dB';
            app.ReceiverLabel = uilabel(app.OutputTab);
            app.ReceiverLabel.Text = 'Receiver';
            app.ReceivedbackEditFieldLabel = uilabel(app.OutputTab);
            app.ReceivedbackEditFieldLabel.Text = 'Received back';
            app.Receivedback = uieditfield(app.OutputTab, 'text');
            app.Receivedback.Value = 'Input message to transmit in input tab';
            app.BEREditFieldLabel = uilabel(app.OutputTab);
            app.BEREditFieldLabel.Text = 'BER';
            app.BER = uieditfield(app.OutputTab, 'numeric');
            app.SNREditFieldLabel = uilabel(app.OutputTab);
            app.SNREditFieldLabel.Text = 'SNR (dB)';
            app.SNR = uieditfield(app.OutputTab, 'numeric');
            app.SNREditFieldLabel_2 = uilabel(app.OutputTab);
            app.SNREditFieldLabel_2.Text = 'Quality Factor';
            app.QualityFactor = uieditfield(app.OutputTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FreeSpaceOpticalCommunicationsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end